classdef Reto_V3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RetoDiagnosticodemalariaUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        NumerodecargasEditField       matlab.ui.control.NumericEditField
        NumerodecargasEditFieldLabel  matlab.ui.control.Label
        DistanciaentrecargasnegativasEditField  matlab.ui.control.NumericEditField
        DistanciaentrecargasnegativasEditFieldLabel  matlab.ui.control.Label
        DistanciaentrecargaspositivasEditField  matlab.ui.control.NumericEditField
        DistanciaentrecargaspositivasEditFieldLabel  matlab.ui.control.Label
        MagnituddecargasnegativasEditField  matlab.ui.control.NumericEditField
        MagnituddecargasnegativasEditFieldLabel  matlab.ui.control.Label
        MagnituddecargaspositivasEditField  matlab.ui.control.NumericEditField
        MagnituddecargaspositivasEditFieldLabel  matlab.ui.control.Label
        PosicionenxdecargasnegativasEditField_2  matlab.ui.control.NumericEditField
        PosicionenxdecargasnegativasEditField_2Label  matlab.ui.control.Label
        PosicionenxdecargaspositivasEditField_2  matlab.ui.control.NumericEditField
        PosicionenxdecargaspositivasEditField_2Label  matlab.ui.control.Label
        GraficarButton                matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarButton
        function Graph(app, event)
            k = 8.98e9;                 % Constante de la ley de Coulomb
            nc = app.NumerodecargasEditField.Value;
            mp = app.MagnituddecargaspositivasEditField.Value;
            mn = app.MagnituddecargasnegativasEditField.Value;
            dcp = app.DistanciaentrecargaspositivasEditField.Value;            
            dcn = app.DistanciaentrecargasnegativasEditField.Value;
            pxp = app.PosicionenxdecargaspositivasEditField_2.Value;            
            pxn = app.PosicionenxdecargasnegativasEditField_2.Value; 
            C = zeros(nc*2, 3);         % Matrices de cargas positivas y negativas

            % Magnitud y posiciones de cargas
            for i = 1:nc                
                C(i, 1) = mp;
                C(i, 2) = pxp;
                C(i, 3) = (-(nc .* (dcp ./ 2)) + (dcp .* (i - 1)) + (dcp/2));
            
                C(i+nc, 1) = mn;
                C(i+nc, 2) = pxn;
                C(i+nc, 3) = (-(nc .* (dcn ./ 2)) + (dcn .* (i - 1)) + (dcn/2));
            end
            
            x = linspace(-30, 30);      % Puntos del grid
            y = linspace(-30, 30);
            [X, Y] = meshgrid(x, y);
            
            Ex = zeros(size(X));        % Campo eléctrico en cada punto
            Ey = zeros(size(Y));
            
            %Calcula campo
            for i = 1:nc                                                        
                rp = sqrt((X - C(i, 2)).^2 + (Y - C(i, 3)).^2+0.8^2);                 % Calcular distancia y vector entre todas las cargas positivas y todos los puntos
                vxp = (X - C(i, 2)) ./ rp;
                vyp = (Y - C(i, 3)) ./ rp;
                
                rn = sqrt((X - C(i+nc, 2)).^2 + (Y - C(i+nc, 3)).^2+0.8^2);           % Calcular distancia y vector entre todas las cargas negativas y todos los puntos
                vxn = (X - C(i+nc, 2)) ./ rn;
                vyn = (Y - C(i+nc, 3)) ./ rn;
               
                Ex = Ex + k .* C(i, 1) .* vxp ./ (rp.^2);                       % Calcular campo por cargas positivas en todos los puntos 
                Ey = Ey + k .* C(i, 1) .* vyp ./ (rp.^2);
                
                Ex = Ex + k .* C(i+nc, 1) .* vxn ./ (rn.^2);                    % Calcular campo por cargas negativas en todos los puntos 
                Ey = Ey + k .* C(i+nc, 1) .* vyn ./ (rn.^2);
            end


            ax = app.UIAxes;            
            cla(ax,"reset")
            ax.XLim = [-30,30];
            ax.YLim = [-30,30];
            hold(ax,"on")
            E = (sqrt((Ex.^2) + (Ey.^2)));
            pcolor(ax,X,Y,E); colormap(ax,'jet');shading(ax,'interp');
            h = streamslice(ax,X,Y,Ex,Ey,2);
           
            set( h, 'Color', [1 1 1] );
            set( h, 'LineWidth',1.5 );


            % Graficar los círculos para cada carga
            t = 0:0.1:2*pi;
            for i = 1:nc                                    
                r2 = abs(.4); 
                x2c = C(i,2) + r2*cos(t); 
                y2c = C(i,3) + r2*sin(t);
                r1 = abs(.4); 
                x1c = C(i+nc,2) + r1*cos(t); 
                y1c = C(i+nc,3) + r1*sin(t);
                c = fill(ax,x2c,y2c,'r',x1c,y1c,'b','EdgeColor','none');
                c.Visible;
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RetoDiagnosticodemalariaUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {792, 792};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RetoDiagnosticodemalariaUIFigure = uifigure('Visible', 'off');
            app.RetoDiagnosticodemalariaUIFigure.Name = 'Reto Diagnostico de malaria';
            app.RetoDiagnosticodemalariaUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.RetoDiagnosticodemalariaUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GraficarButton = uibutton(app.LeftPanel, 'push');
            app.GraficarButton.ButtonPushedFcn = createCallbackFcn(app, @Graph, true);
            app.GraficarButton.Text = 'Graficar';
            app.PosicionenxdecargaspositivasEditField_2Label = uilabel(app.LeftPanel);
            app.PosicionenxdecargaspositivasEditField_2Label.Text = 'Posicion en x de cargas positivas';
            app.PosicionenxdecargaspositivasEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.PosicionenxdecargaspositivasEditField_2.Value = -4;
            app.PosicionenxdecargasnegativasEditField_2Label = uilabel(app.LeftPanel);
            app.PosicionenxdecargasnegativasEditField_2Label.Text = 'Posicion en x de cargas negativas';
            app.PosicionenxdecargasnegativasEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.PosicionenxdecargasnegativasEditField_2.Value = 4;
            app.MagnituddecargaspositivasEditFieldLabel = uilabel(app.LeftPanel);
            app.MagnituddecargaspositivasEditFieldLabel.Text = 'Magnitud de cargas positivas';
            app.MagnituddecargaspositivasEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MagnituddecargaspositivasEditField.Value = 20;
            app.MagnituddecargasnegativasEditFieldLabel = uilabel(app.LeftPanel);
            app.MagnituddecargasnegativasEditFieldLabel.Text = 'Magnitud de cargas negativas';
            app.MagnituddecargasnegativasEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MagnituddecargasnegativasEditField.Value = -20;
            app.DistanciaentrecargaspositivasEditFieldLabel = uilabel(app.LeftPanel);
            app.DistanciaentrecargaspositivasEditFieldLabel.Text = 'Distancia entre cargas positivas';
            app.DistanciaentrecargaspositivasEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DistanciaentrecargaspositivasEditField.Value = 0.01;
            app.DistanciaentrecargasnegativasEditFieldLabel = uilabel(app.LeftPanel);
            app.DistanciaentrecargasnegativasEditFieldLabel.Text = 'Distancia entre cargas negativas';
            app.DistanciaentrecargasnegativasEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DistanciaentrecargasnegativasEditField.Value = 0.02;
            app.NumerodecargasEditFieldLabel = uilabel(app.LeftPanel);
            app.NumerodecargasEditFieldLabel.Text = 'Numero de cargas';
            app.NumerodecargasEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumerodecargasEditField.Value = 500;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Campo electrico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-30 30];
            app.UIAxes.YLim = [-30 30];
            app.UIAxes.XTick = [-30 -20 -10 0 10 20 30];
            app.UIAxes.XTickLabel = {'-30'; '-20'; '-10'; '0'; '10'; '20'; '30'};
            app.UIAxes.YTick = [-30 -20 -10 0 10 20 30];
            app.UIAxes.YTickLabel = {'-30'; '-20'; '-10'; '0'; '10'; '20'; '30'};
            % Show the figure after all components are created
            app.RetoDiagnosticodemalariaUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Reto_V3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RetoDiagnosticodemalariaUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RetoDiagnosticodemalariaUIFigure)
        end
    end
end